classdef ManipulatorX < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ClearGraphsButton     matlab.ui.control.Button
        INVERSEPanel          matlab.ui.container.Panel
        vmaxBox               matlab.ui.control.NumericEditField
        amaxBox               matlab.ui.control.NumericEditField
        PyEditFieldLabel_3    matlab.ui.control.Label
        PxEditFieldLabel_3    matlab.ui.control.Label
        PzEditFieldLabel_3    matlab.ui.control.Label
        Pitch_Inv             matlab.ui.control.NumericEditField
        PLANNINGButton        matlab.ui.control.Button
        Pz_Inv                matlab.ui.control.NumericEditField
        Py_Inv                matlab.ui.control.NumericEditField
        Px_Inv                matlab.ui.control.NumericEditField
        PzEditFieldLabel_2    matlab.ui.control.Label
        PyEditFieldLabel_2    matlab.ui.control.Label
        PxEditFieldLabel_2    matlab.ui.control.Label
        INVERSEButton         matlab.ui.control.Button
        ENDEFFECTORPanel      matlab.ui.container.Panel
        WorkSpaceCheckBox     matlab.ui.control.CheckBox
        Coordinate_Checkbox   matlab.ui.control.CheckBox
        Yaw_EE                matlab.ui.control.NumericEditField
        YawEditFieldLabel     matlab.ui.control.Label
        Roll_EE               matlab.ui.control.NumericEditField
        RollEditFieldLabel    matlab.ui.control.Label
        Pitch_EE              matlab.ui.control.NumericEditField
        PitchEditFieldLabel   matlab.ui.control.Label
        Z_EE                  matlab.ui.control.NumericEditField
        PzEditFieldLabel      matlab.ui.control.Label
        Y_EE                  matlab.ui.control.NumericEditField
        PyEditFieldLabel      matlab.ui.control.Label
        X_EE                  matlab.ui.control.NumericEditField
        PxEditFieldLabel      matlab.ui.control.Label
        FORWARDPanel          matlab.ui.container.Panel
        STOPButton            matlab.ui.control.Button
        Clear                 matlab.ui.control.Button
        RESETButton           matlab.ui.control.Button
        Theta4degSliderLabel  matlab.ui.control.Label
        FORWARDButton         matlab.ui.control.Button
        Theta4EditField       matlab.ui.control.NumericEditField
        Theta3EditField       matlab.ui.control.NumericEditField
        Theta2EditField       matlab.ui.control.NumericEditField
        Theta1EditField       matlab.ui.control.NumericEditField
        Theta4Slider          matlab.ui.control.Slider
        Theta3Slider          matlab.ui.control.Slider
        Theta3degLabel        matlab.ui.control.Label
        Theta2Slider          matlab.ui.control.Slider
        Theta2degSliderLabel  matlab.ui.control.Label
        Theta1Slider          matlab.ui.control.Slider
        Theta1degSliderLabel  matlab.ui.control.Label
        UIAxesVel4            matlab.ui.control.UIAxes
        UIAxesVel3            matlab.ui.control.UIAxes
        UIAxesVel2            matlab.ui.control.UIAxes
        UIAxesVel1            matlab.ui.control.UIAxes
        UIAxesTheta4          matlab.ui.control.UIAxes
        UIAxesTheta3          matlab.ui.control.UIAxes
        UIAxesTheta2          matlab.ui.control.UIAxes
        UIAxesTheta1          matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end


    properties (Access = private) 
        %% 1. Trạng thái hệ thống (System Flags)
        isBusy = false;       % Cờ khóa hệ thống khi robot đang di chuyển
        stopRequest = false;  % Cờ tín hiệu dừng khẩn cấp

        %% 2. Thông số Động học Robot (Robot Kinematics)
        % Bảng tham số D-H (Denavit-Hartenberg)
        theta = [0, pi/2, -pi/2, 0, 0];
        d     = [77, 0, 0, 0, 0];
        a     = [0, 128, 24, 124, 126];
        alpha = [pi/2, 0, 0, 0, 0];
        
        % Các ma trận biến đổi đồng nhất (Transformation Matrices)
        T01, T1a, Ta2, T23, T34
        T0a, T02, T03, T04
        
        % Biến lưu trữ góc khớp (Angles)
        old_theta = [0, 0, 0, 0];            % Vị trí hiện tại
        new_theta = [0, 0, 0, 0];            % Vị trí đích đến
        theta_offset_deg = [0, 90, -90, 0];  % Độ lệch giữa góc D-H và góc hiển thị GUI
        
        % Ma trận xoay phụ trợ
        Rotate_Y = [cos(pi/2) 0 sin(pi/2) 0; 0 1 0 0; -sin(pi/2) 0 cos(pi/2) 0; 0 0 0 1];

        %% 3. Biến Quy hoạch quỹ đạo & Động học ngược (Trajectory & IK)
        robotPath = [];         % Lưu vết đường đi của điểm tác động cuối (End-Effector)
        h_PathPlot;             % Handle đồ họa đường dẫn
        eulerAngles = [];       % Góc Roll-Pitch-Yaw
        Px = 0; Py = 0; Pz = 0; Pitch = 0; % Tọa độ đầu vào bài toán ngược
        vmax; amax;             % Giới hạn vận tốc, gia tốc
        thoigian = [];          % Mảng thời gian mô phỏng quỹ đạo

        %% 4. Đối tượng Đồ họa (Graphics Handles)
        % Lưu trữ handle để cập nhật thuộc tính thay vì vẽ lại (tăng hiệu suất)
        h_Base_Surf; h_Base_Top; h_Base_Bot; h_BaseBox_Faces;
        h_Trus1_Surf; h_Trus1_Top; h_Trus1_Bot;
        h_Joint1_Surf; h_Joint1_Top; h_Joint1_Bot;
        h_Link1_Surf; h_Link1_Top; h_Link1_Bot;
        h_Joint2_Box; h_Joint2_Cyl; h_Joint2_Top; h_Joint2_Bot;
        h_Link2_Surf; h_Link2_Top; h_Link2_Bot;
        h_Joint3_Surf; h_Joint3_Top; h_Joint3_Bot;
        h_Link3_Surf; h_Link3_Top; h_Link3_Bot;
        h_EE_Surf; h_EE_Top; h_EE_Bot;
        h_Workspace;
        h_Coords = {}; h_Labels = {}; % Tập hợp các trục tọa độ

        %% 5. Dữ liệu hình học gốc (Pre-calculated Geometry)
        geo_UnitCyl_X; geo_UnitCyl_Y; geo_UnitCyl_Z; 
        geo_Box_Verts; geo_Box_Faces; 
        geo_BaseBox_X; geo_BaseBox_Y; geo_BaseBox_Z; 
        geo_EE_X; geo_EE_Y; geo_EE_Z;
    end    
 

    methods (Access = private)
        %% ----------------------------------------------------------------
        % MODULE 1: TOÁN HỌC CỐT LÕI (CORE MATHEMATICS)
        % ----------------------------------------------------------------
        
        % Tính ma trận biến đổi D-H cho khớp thứ n
        function T = DH_Matrix(app, n)
            ct = cos(app.theta(n)); 
            st = sin(app.theta(n));
            ca = cos(app.alpha(n)); 
            sa = sin(app.alpha(n));
            
            T = [ct, -ca*st,  sa*st, app.a(n)*ct;
                 st,  ca*ct, -sa*ct, app.a(n)*st;
                 0,   sa,     ca,    app.d(n);
                 0,   0,      0,     1];
        end
        
        % Giải bài toán Động học ngược (Inverse Kinematics - IK)
        % Input: Tọa độ Px, Py, Pz và góc Pitch (độ)
        % Output: Cờ thành công (success) và mảng góc khớp (angles)
        function [success, angles] = Solve_Inverse_Kinematics(~, px, py, pz, pitch_deg)
            success = false; 
            angles = [0 0 0 0];
            
            % Kích thước các khâu
            d1 = 77; L2 = 128; L3 = 148; L4 = 176; 
            phi = deg2rad(-pitch_deg);

            % 1. Tính góc quay thân (Theta 1)
            theta1_raw = atan2(py, px);
            pr = sqrt(px^2 + py^2);
            
            % Xử lý góc bù khi quay ra sau lưng
            if abs(theta1_raw) > pi/2
                if theta1_raw > 0
                    theta1 = theta1_raw - pi;
                else
                    theta1 = theta1_raw + pi;
                end
                pr = -pr; 
            else
                theta1 = theta1_raw;
            end

            % 2. Xác định tâm cổ tay (Wrist Center)
            r_wc = pr - L4 * cos(phi);
            z_wc = (pz - d1) - L4 * sin(phi);

            % 3. Áp dụng định lý Cosin cho khớp 2 và 3
            numerator = r_wc^2 + z_wc^2 - L2^2 - L3^2;
            denominator = 2 * L2 * L3;
            D = numerator / denominator;

            % Kiểm tra điểm đích có nằm trong vùng làm việc không
            if abs(D) > 1
                return; % Thất bại: Không với tới
            end
            
            theta3 = -acos(D); 
            theta2 = atan2(z_wc, r_wc) - atan2(L3 * sin(theta3), L2 + L3 * cos(theta3));
            theta4 = phi - theta2 - theta3;

            angles = [theta1, theta2, theta3, theta4];
            success = true;
        end

        % Hàm tiện ích: Cập nhật biến mục tiêu khi người dùng thao tác Slider
        function Update_Single_Joint(app, idx, val)
             app.new_theta(idx) = deg2rad(val + app.theta_offset_deg(idx));
        end

        %% ----------------------------------------------------------------
        % MODULE 2: ĐỒ HỌA & HIỂN THỊ (GRAPHICS & VISUALIZATION)
        % ----------------------------------------------------------------
        
        % Khởi tạo dữ liệu hình học cơ bản (Chạy 1 lần khi khởi động)
        function Init_Geometry_Data(app)
            % Tạo hình trụ đơn vị
            [app.geo_UnitCyl_X, app.geo_UnitCyl_Y, app.geo_UnitCyl_Z] = cylinder(1, 20); 
            
            % Tạo hình hộp cho Khớp 2
            L=40; W=40; H=40;
            x = [-H/2 H/2 H/2 -H/2 -H/2 H/2 H/2 -H/2]; 
            y = [-W/2 -W/2 W/2 W/2 -W/2 -W/2 W/2 W/2]; 
            z = [-L/2 -L/2 -L/2 -L/2  L/2  L/2  L/2  L/2];
            app.geo_Box_Verts = [x; y; z; ones(1,8)];
            app.geo_Box_Faces = [1 2 3 4; 5 6 7 8; 1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8];

            % Tạo hình hộp Đế (Base)
            L_b=60; W_b=80; H_b=40;
            [X1,Z1]=meshgrid([-L_b L_b/2],[0 H_b]); Y1=-W_b/2*ones(size(X1));
            [X2,Z2]=meshgrid([-L_b L_b/2],[0 H_b]); Y2= W_b/2*ones(size(X2));
            [Y3,Z3]=meshgrid([-W_b/2 W_b/2],[0 H_b]); X3=-L_b*ones(size(Y3));
            [Y4,Z4]=meshgrid([-W_b/2 W_b/2],[0 H_b]); X4= L_b/2*ones(size(Y4));
            app.geo_BaseBox_X = {X1, X2, X3, X4, [-L_b L_b/2 L_b/2 -L_b], [-L_b L_b/2 L_b/2 -L_b]};
            app.geo_BaseBox_Y = {Y1, Y2, Y3, Y4, [-W_b/2 -W_b/2 W_b/2 W_b/2], [-W_b/2 -W_b/2 W_b/2 W_b/2]};
            app.geo_BaseBox_Z = {Z1, Z2, Z3, Z4, [0 0 0 0], [H_b H_b H_b H_b]};

            % Tạo hình End Effector
            x_e = [ 5 50 40 30 20 -20 -30 -20 20 30 40 50 5]; 
            z_e = [-10 -10 -20 -20 -30 -40 0 40 30 20 20 10 10]; 
            y_e = [10*ones(1,13); -10*ones(1,13)];
            app.geo_EE_X = repmat(x_e, 2, 1); 
            app.geo_EE_Z = repmat(z_e, 2, 1); 
            app.geo_EE_Y = y_e;
            
            % Khởi tạo đường vẽ quỹ đạo (Path Plot)
            hold(app.UIAxes, "on");
            app.h_PathPlot = plot3(app.UIAxes, 0, 0, 0, 'LineWidth', 2, 'Color', 'b');
        end
        
        % Biến đổi tọa độ hình trụ theo ma trận T
        function [X_new, Y_new, Z_new] = Transform_Cylinder(~, X, Y, Z, T_Matrix, Radius, Scale_Z, Offset_Z, Pre_Rotate)
             X_mod = X * Radius; Y_mod = Y * Radius; Z_mod = Z * Scale_Z + Offset_Z;
             A = [X_mod(1, :); Y_mod(1, :); Z_mod(1, :); ones(1, size(X, 2))];
             B = [X_mod(2, :); Y_mod(2, :); Z_mod(2, :); ones(1, size(X, 2))];
             if nargin > 8 && ~isempty(Pre_Rotate)
                 A = Pre_Rotate * A; B = Pre_Rotate * B; 
             end
             A_new = T_Matrix * A; B_new = T_Matrix * B;
             X_new = [A_new(1, :); B_new(1, :)]; Y_new = [A_new(2, :); B_new(2, :)]; Z_new = [A_new(3, :); B_new(3, :)];
        end

        % Hàm vẽ chính: Cập nhật vị trí toàn bộ Robot trên UIAxes
        function Draw_Robot(app)
            % 1. Tính toán động học thuận (Forward Kinematics)
            app.T01 = DH_Matrix(app,1); app.T1a = DH_Matrix(app,2);
            app.Ta2 = DH_Matrix(app,3); app.T23 = DH_Matrix(app,4); app.T34 = DH_Matrix(app,5);
            app.T0a = app.T01*app.T1a; app.T02 = app.T0a*app.Ta2;
            app.T03 = app.T02*app.T23; app.T04 = app.T03*app.T34;

            % Định nghĩa màu sắc
            c_blue = [0.00 0.45 0.74]; c_gray = [0.8 0.8 0.8]; 
            c_yellow = [0.93 0.69 0.13]; c_red = [0.64 0.08 0.18];

            % 2. Vẽ Đế (Base) - Chỉ vẽ lần đầu tiên
            if isempty(app.h_Base_Surf)
                [Xn, Yn, Zn] = app.Transform_Cylinder(app.geo_UnitCyl_X, app.geo_UnitCyl_Y, app.geo_UnitCyl_Z, eye(4), 30, 40, 0, []);
                app.h_Base_Surf = surf(app.UIAxes, Xn, Yn, Zn, 'FaceColor', c_blue, 'EdgeColor', 'none');
                app.h_Base_Top = fill3(app.UIAxes, Xn(2,:), Yn(2,:), Zn(2,:), c_blue);
                app.h_Base_Bot = fill3(app.UIAxes, Xn(1,:), Yn(1,:), Zn(1,:), c_blue);
                for i=1:4 
                    surf(app.UIAxes, app.geo_BaseBox_X{i}, app.geo_BaseBox_Y{i}, app.geo_BaseBox_Z{i}, 'FaceColor', c_blue, 'EdgeColor', 'none'); 
                end
                fill3(app.UIAxes, app.geo_BaseBox_X{5}, app.geo_BaseBox_Y{5}, app.geo_BaseBox_Z{5}, c_blue);
                fill3(app.UIAxes, app.geo_BaseBox_X{6}, app.geo_BaseBox_Y{6}, app.geo_BaseBox_Z{6}, c_blue);
            end

            % 3. Cập nhật vị trí các Link và Joint (Khớp & Khâu)
            % --- Trục 1 & Joint 1 & Link 1 ---
            [Xn, Yn, Zn] = app.Transform_Cylinder(app.geo_UnitCyl_X, app.geo_UnitCyl_Y, app.geo_UnitCyl_Z, eye(4), 10, 77, 0, []);
            if isempty(app.h_Trus1_Surf)
                app.h_Trus1_Surf = surf(app.UIAxes, Xn, Yn, Zn, 'FaceColor', c_gray, 'EdgeColor', 'none');
                app.h_Trus1_Top = fill3(app.UIAxes, Xn(2,:), Yn(2,:), Zn(2,:), 'blue'); 
                app.h_Trus1_Bot = fill3(app.UIAxes, Xn(1,:), Yn(1,:), Zn(1,:), 'blue');
            else
                set(app.h_Trus1_Surf, 'XData', Xn, 'YData', Yn, 'ZData', Zn); 
                set(app.h_Trus1_Top, 'XData', Xn(2,:), 'YData', Yn(2,:), 'ZData', Zn(2,:)); 
                set(app.h_Trus1_Bot, 'XData', Xn(1,:), 'YData', Yn(1,:), 'ZData', Zn(1,:)); 
            end
            
            [Xn, Yn, Zn] = app.Transform_Cylinder(app.geo_UnitCyl_X, app.geo_UnitCyl_Y, app.geo_UnitCyl_Z, app.T01, 20, 50, -25, []);
            if isempty(app.h_Joint1_Surf)
                app.h_Joint1_Surf = surf(app.UIAxes, Xn, Yn, Zn, 'FaceColor', c_yellow, 'EdgeColor', 'none');
                app.h_Joint1_Top = fill3(app.UIAxes, Xn(2,:), Yn(2,:), Zn(2,:), c_yellow); 
                app.h_Joint1_Bot = fill3(app.UIAxes, Xn(1,:), Yn(1,:), Zn(1,:), c_yellow);
            else
                set(app.h_Joint1_Surf, 'XData', Xn, 'YData', Yn, 'ZData', Zn); 
                set(app.h_Joint1_Top, 'XData', Xn(2,:), 'YData', Yn(2,:), 'ZData', Zn(2,:)); 
                set(app.h_Joint1_Bot, 'XData', Xn(1,:), 'YData', Yn(1,:), 'ZData', Zn(1,:)); 
            end

            [Xn, Yn, Zn] = app.Transform_Cylinder(app.geo_UnitCyl_X, app.geo_UnitCyl_Y, app.geo_UnitCyl_Z, app.T0a, 10, 128, -128, app.Rotate_Y);
            if isempty(app.h_Link1_Surf)
                app.h_Link1_Surf = surf(app.UIAxes, Xn, Yn, Zn, 'FaceColor', c_gray, 'EdgeColor', 'none');
                app.h_Link1_Top = fill3(app.UIAxes, Xn(2,:), Yn(2,:), Zn(2,:), c_gray); 
                app.h_Link1_Bot = fill3(app.UIAxes, Xn(1,:), Yn(1,:), Zn(1,:), c_gray);
            else
                set(app.h_Link1_Surf, 'XData', Xn, 'YData', Yn, 'ZData', Zn); 
                set(app.h_Link1_Top, 'XData', Xn(2,:), 'YData', Yn(2,:), 'ZData', Zn(2,:)); 
                set(app.h_Link1_Bot, 'XData', Xn(1,:), 'YData', Yn(1,:), 'ZData', Zn(1,:)); 
            end

            % --- Joint 2 (Kết hợp Hộp & Trụ) ---
            P_new = app.T0a * app.geo_Box_Verts;
            if isempty(app.h_Joint2_Box)
                 app.h_Joint2_Box = patch(app.UIAxes, 'Vertices', P_new(1:3,:)', 'Faces', app.geo_Box_Faces, 'FaceColor', c_blue, 'EdgeColor', 'black');
            else
                 set(app.h_Joint2_Box, 'Vertices', P_new(1:3,:)'); 
            end
            
            [Xn, Yn, Zn] = app.Transform_Cylinder(app.geo_UnitCyl_X, app.geo_UnitCyl_Y, app.geo_UnitCyl_Z, app.T02, 20, 50, -25, []);
            if isempty(app.h_Joint2_Cyl)
                app.h_Joint2_Cyl = surf(app.UIAxes, Xn, Yn, Zn, 'FaceColor', c_yellow, 'EdgeColor', 'none');
                app.h_Joint2_Top = fill3(app.UIAxes, Xn(2,:), Yn(2,:), Zn(2,:), c_yellow); 
                app.h_Joint2_Bot = fill3(app.UIAxes, Xn(1,:), Yn(1,:), Zn(1,:), c_yellow);
            else
                set(app.h_Joint2_Cyl, 'XData', Xn, 'YData', Yn, 'ZData', Zn); 
                set(app.h_Joint2_Top, 'XData', Xn(2,:), 'YData', Yn(2,:), 'ZData', Zn(2,:)); 
                set(app.h_Joint2_Bot, 'XData', Xn(1,:), 'YData', Yn(1,:), 'ZData', Zn(1,:)); 
            end

            % --- Link 2 & Joint 3 ---
            [Xn, Yn, Zn] = app.Transform_Cylinder(app.geo_UnitCyl_X, app.geo_UnitCyl_Y, app.geo_UnitCyl_Z, app.T03, 10, 124, -124, app.Rotate_Y);
            if isempty(app.h_Link2_Surf)
                app.h_Link2_Surf = surf(app.UIAxes, Xn, Yn, Zn, 'FaceColor', c_red, 'EdgeColor', 'none');
                app.h_Link2_Top = fill3(app.UIAxes, Xn(2,:), Yn(2,:), Zn(2,:), c_red); 
                app.h_Link2_Bot = fill3(app.UIAxes, Xn(1,:), Yn(1,:), Zn(1,:), c_red);
            else
                set(app.h_Link2_Surf, 'XData', Xn, 'YData', Yn, 'ZData', Zn); 
                set(app.h_Link2_Top, 'XData', Xn(2,:), 'YData', Yn(2,:), 'ZData', Zn(2,:)); 
                set(app.h_Link2_Bot, 'XData', Xn(1,:), 'YData', Yn(1,:), 'ZData', Zn(1,:)); 
            end

            [Xn, Yn, Zn] = app.Transform_Cylinder(app.geo_UnitCyl_X, app.geo_UnitCyl_Y, app.geo_UnitCyl_Z, app.T03, 20, 50, -25, []);
            if isempty(app.h_Joint3_Surf)
                app.h_Joint3_Surf = surf(app.UIAxes, Xn, Yn, Zn, 'FaceColor', c_blue, 'EdgeColor', 'none');
                app.h_Joint3_Top = fill3(app.UIAxes, Xn(2,:), Yn(2,:), Zn(2,:), c_gray); 
                app.h_Joint3_Bot = fill3(app.UIAxes, Xn(1,:), Yn(1,:), Zn(1,:), c_gray);
            else
                set(app.h_Joint3_Surf, 'XData', Xn, 'YData', Yn, 'ZData', Zn); 
                set(app.h_Joint3_Top, 'XData', Xn(2,:), 'YData', Yn(2,:), 'ZData', Zn(2,:)); 
                set(app.h_Joint3_Bot, 'XData', Xn(1,:), 'YData', Yn(1,:), 'ZData', Zn(1,:)); 
            end

            % --- Link 3 & End Effector ---
            [Xn, Yn, Zn] = app.Transform_Cylinder(app.geo_UnitCyl_X, app.geo_UnitCyl_Y, app.geo_UnitCyl_Z, app.T04, 10, 126, -126, app.Rotate_Y);
            if isempty(app.h_Link3_Surf)
                app.h_Link3_Surf = surf(app.UIAxes, Xn, Yn, Zn, 'FaceColor', c_gray, 'EdgeColor', 'none');
                app.h_Link3_Top = fill3(app.UIAxes, Xn(2,:), Yn(2,:), Zn(2,:), c_yellow); 
                app.h_Link3_Bot = fill3(app.UIAxes, Xn(1,:), Yn(1,:), Zn(1,:), c_yellow);
            else
                set(app.h_Link3_Surf, 'XData', Xn, 'YData', Yn, 'ZData', Zn); 
                set(app.h_Link3_Top, 'XData', Xn(2,:), 'YData', Yn(2,:), 'ZData', Zn(2,:)); 
                set(app.h_Link3_Bot, 'XData', Xn(1,:), 'YData', Yn(1,:), 'ZData', Zn(1,:)); 
            end
            
            A = [app.geo_EE_X(1,:); app.geo_EE_Y(1,:); app.geo_EE_Z(1,:); ones(1, size(app.geo_EE_X, 2))];  
            B = [app.geo_EE_X(2,:); app.geo_EE_Y(2,:); app.geo_EE_Z(2,:); ones(1, size(app.geo_EE_X, 2))]; 
            A_new = app.T04 * A; B_new = app.T04 * B;        
            xe_n = [A_new(1,:); B_new(1,:)]; 
            ye_n = [A_new(2,:); B_new(2,:)]; 
            ze_n = [A_new(3,:); B_new(3,:)];

            if isempty(app.h_EE_Surf)
                app.h_EE_Surf = surf(app.UIAxes, xe_n, ye_n, ze_n, 'FaceColor', c_yellow);
                app.h_EE_Top = fill3(app.UIAxes, xe_n(1,:), ye_n(1,:), ze_n(1,:), c_blue); 
                app.h_EE_Bot = fill3(app.UIAxes, xe_n(2,:), ye_n(2,:), ze_n(2,:), c_blue); 
            else
                set(app.h_EE_Surf, 'XData', xe_n, 'YData', ye_n, 'ZData', ze_n); 
                set(app.h_EE_Top, 'XData', xe_n(1,:), 'YData', ye_n(1,:), 'ZData', ze_n(1,:)); 
                set(app.h_EE_Bot, 'XData', xe_n(2,:), 'YData', ye_n(2,:), 'ZData', ze_n(2,:)); 
            end

            % 4. Cập nhật Tọa độ EE & Vẽ đường quỹ đạo
            P_ee = app.T04 * [50; 0; 0; 1];
            app.X_EE.Value = P_ee(1); app.Y_EE.Value = P_ee(2); app.Z_EE.Value = P_ee(3);
            app.robotPath = [app.robotPath, P_ee(1:3)];
            set(app.h_PathPlot, 'XData', app.robotPath(1,:), 'YData', app.robotPath(2,:), 'ZData', app.robotPath(3,:));

            % 5. Tính góc Euler (Roll-Pitch-Yaw) để hiển thị
            cospitch = sqrt((app.T04(1,1))^2 + (app.T04(2,1))^2); 
            pitch = atan2(-app.T04(3,1), cospitch);                    
            if pitch == pi/2
                yaw = 0; roll = atan2(app.T04(1,2), app.T04(2,2));              
            elseif pitch == -pi/2
                yaw = 0; roll = atan2(-app.T04(1,2), app.T04(2,2));             
            else
                yaw = atan2(app.T04(2,1)/cos(pitch), app.T04(1,1)/cos(pitch)); 
                roll = atan2(app.T04(3,2)/cos(pitch), app.T04(3,3)/cos(pitch)); 
            end
            app.eulerAngles = [roll pitch yaw] * (180/pi); 
            app.Roll_EE.Value = app.eulerAngles(1); app.Pitch_EE.Value = app.eulerAngles(2); app.Yaw_EE.Value = app.eulerAngles(3);  
            
            % 6. Cập nhật hệ tọa độ gắn trên các khớp
            app.Update_Coordinates();
            
            % 7. Kiểm tra và vẽ Vùng làm việc (Workspace)
            if isprop(app,'WorkSpaceCheckBox') && app.WorkSpaceCheckBox.Value
                if isempty(app.h_Workspace)
                    r = 450; z_center = 77;
                    [u, v] = meshgrid(linspace(0, 2*pi, 20), linspace(0, pi, 20));
                    x = r .* cos(u) .* sin(v); y = r .* sin(u) .* sin(v); z = r .* cos(v) + z_center;
                    hold(app.UIAxes, "on");
                    app.h_Workspace = surf(app.UIAxes, x, y, z, 'EdgeColor', 'none', 'FaceColor', [0.5 0.5 0.5], 'FaceAlpha', 0.15);
                else
                    app.h_Workspace.Visible = 'on'; 
                end
            elseif ~isempty(app.h_Workspace)
                app.h_Workspace.Visible = 'off'; 
            end
        end        

        % Hàm Animation: Di chuyển robot mượt mà (PTP)
        function Animate_Robot(app)
            steps = 30; delay = 0.0001;
            app.stopRequest = false;
            
            theta_start = app.old_theta;
            theta_end = [app.new_theta(1), app.new_theta(2), app.new_theta(3), app.new_theta(4)];
            delta = (theta_end - theta_start) / steps;
            current = theta_start;
            
            % Chuẩn bị các UI elements để cập nhật ngược khi dừng
            sliders = {app.Theta1Slider, app.Theta2Slider, app.Theta3Slider, app.Theta4Slider};
            fields = {app.Theta1EditField, app.Theta2EditField, app.Theta3EditField, app.Theta4EditField};

            for i = 1:steps
                % Logic dừng khẩn cấp (Emergency Stop)
                if app.stopRequest
                    app.old_theta = current; app.new_theta = current;
                    for k=1:4 
                        val = rad2deg(current(k)) - app.theta_offset_deg(k);
                        val = mod(val + 180, 360) - 180;
                        % Kẹp giá trị an toàn
                        if val < sliders{k}.Limits(1), val = sliders{k}.Limits(1); end
                        if val > sliders{k}.Limits(2), val = sliders{k}.Limits(2); end
                        sliders{k}.Value = val; fields{k}.Value = val;
                    end
                    uialert(app.UIFigure, 'Emergency stop activated!', 'Alert', 'Icon', 'warning');    
                    app.stopRequest = false; app.isBusy = false; return;
                end
                
                current = current + delta;
                app.theta([1 2 3 5]) = current; % Cập nhật theta D-H
                app.Draw_Robot();
                drawnow; pause(delay); 
            end
            app.old_theta = theta_end; % Hoàn tất hành trình
        end
        
        % Hàm vẽ các trục tọa độ gắn trên mỗi khớp (Frames)
        function Update_Coordinates(app)
            if ~app.Coordinate_Checkbox.Value
                if ~isempty(app.h_Coords)
                     for i=1:length(app.h_Coords)
                         if isvalid(app.h_Coords{i}), app.h_Coords{i}.Visible='off'; end
                     end
                     for i=1:length(app.h_Labels)
                         if isvalid(app.h_Labels{i}), app.h_Labels{i}.Visible='off'; end
                     end
                end
                return;
            end
            axis_names = {'X', 'Y', 'Z'};
            matrices = {eye(4), app.T01, app.T0a, app.T02, app.T03, app.T04};
            colors = {'r', 'g', 'b'};
            
            if isempty(app.h_Coords)
                 app.h_Coords = {}; app.h_Labels = {};
                 for m=1:length(matrices)
                     origin = matrices{m} * [0 0 0 1]';
                     for axis=1:3
                         vec = [0 0 0 1]'; vec(axis) = 100; pt = matrices{m} * vec;
                         q = quiver3(app.UIAxes, origin(1), origin(2), origin(3), pt(1)-origin(1), pt(2)-origin(2), pt(3)-origin(3), colors{axis}, 'LineWidth', 2);
                         app.h_Coords{end+1} = q;
                         txt = text(app.UIAxes, pt(1), pt(2), pt(3), axis_names{axis}, 'Color', colors{axis}, 'FontWeight', 'bold', 'FontSize', 8);
                         app.h_Labels{end+1} = txt;
                     end
                 end
            else
                 idx = 1;
                 for m=1:length(matrices)
                     origin = matrices{m} * [0 0 0 1]';
                     for axis=1:3
                         vec = [0 0 0 1]'; vec(axis) = 100; pt = matrices{m} * vec;
                         if idx <= length(app.h_Coords) && isvalid(app.h_Coords{idx})
                             set(app.h_Coords{idx}, 'XData', origin(1), 'YData', origin(2), 'ZData', origin(3), 'UData', pt(1)-origin(1), 'VData', pt(2)-origin(2), 'WData', pt(3)-origin(3), 'Visible', 'on');
                         end
                         if idx <= length(app.h_Labels) && isvalid(app.h_Labels{idx})
                             set(app.h_Labels{idx}, 'Position', pt(1:3), 'Visible', 'on');
                         end
                         idx = idx + 1;
                     end
                 end
            end
        end

        %% ----------------------------------------------------------------
        % MODULE 3: QUY HOẠCH QUỸ ĐẠO (TRAJECTORY PLANNING)
        % ----------------------------------------------------------------

        % Hàm Quy hoạch quỹ đạo LSPB (Linear Segment with Parabolic Blend)
        function LSPB_TrajectoryPlanning(app)
            num_joints = 4; dt = 0.05;
            delta_Q = app.new_theta(1:num_joints) - app.old_theta(1:num_joints);
            dist = abs(delta_Q); 
            V_limit = repmat(app.vmax, 1, num_joints); A_limit = repmat(app.amax, 1, num_joints); 
            
            % 1. Tính toán thời gian đồng bộ (Synchronization Time)
            T_needed = zeros(1, num_joints);
            for k = 1:num_joints
                if dist(k) < 1e-4
                    T_needed(k) = 0; continue; 
                end
                v_peak_possible = sqrt(dist(k) * A_limit(k)); 
                V_use = min(V_limit(k), v_peak_possible * 0.95);
                t_acc = V_use / A_limit(k);
                T_needed(k) = 2 * t_acc + (dist(k) - V_use*t_acc) / V_use;
            end
            T_common = max(T_needed);
            if T_common == 0, return; end
            
            app.thoigian = 0:dt:T_common; len_t = length(app.thoigian);
            
            % 2. Tính lại vận tốc đồng bộ
            V_sync = zeros(1, num_joints);
            for k = 1:num_joints
                if dist(k) < 1e-4, continue; end
                delta_eq = (A_limit(k)*T_common)^2 - 4 * A_limit(k) * dist(k);
                if delta_eq >= 0
                    V_sync(k) = (A_limit(k)*T_common - sqrt(delta_eq)) / 2;
                else
                    V_sync(k) = dist(k) / T_common * 1.5; 
                end
            end

            % 3. Tạo quỹ đạo vị trí (Q) và vận tốc (V)
            Q_traj = zeros(num_joints, len_t); V_traj = zeros(num_joints, len_t);
            for k = 1:num_joints
                if dist(k) < 1e-4
                    Q_traj(k, :) = app.old_theta(k); continue; 
                end
                v = V_sync(k); acc = A_limit(k); t_acc = v/acc; t_dec = T_common - t_acc; sign_q = sign(delta_Q(k));
                for i = 1:len_t
                    t = app.thoigian(i);
                    if t < t_acc
                        q_val = 0.5*acc*t^2; v_val = acc*t;
                    elseif t <= t_dec
                        q_acc=0.5*acc*t_acc^2; q_val=q_acc+v*(t-t_acc); v_val=v;
                    elseif t <= T_common
                        t_rem=T_common-t; q_val=dist(k)-0.5*acc*t_rem^2; v_val=acc*t_rem;
                    else
                        q_val=dist(k); v_val=0; 
                    end
                    Q_traj(k, i) = app.old_theta(k) + sign_q * q_val; V_traj(k, i) = sign_q * v_val;
                end
            end
            
            % 4. Vẽ đồ thị và chạy mô phỏng
            axes_theta = {app.UIAxesTheta1, app.UIAxesTheta2, app.UIAxesTheta3, app.UIAxesTheta4};
            axes_vel   = {app.UIAxesVel1, app.UIAxesVel2, app.UIAxesVel3, app.UIAxesVel4};
            for ax = [axes_theta, axes_vel]
                hold(ax{1}, 'off'); 
            end

            for i = 1:len_t
                if app.stopRequest
                    app.stopRequest = false; app.new_theta = Q_traj(:, i)';
                    uialert(app.UIFigure, 'Đã dừng quy hoạch!', 'Thông báo'); return; 
                end
                
                app.theta([1 2 3 5]) = Q_traj(:, i); 
                app.Draw_Robot();
                
                % Vẽ đồ thị theo thời gian thực (Real-time plotting)
                if mod(i, 2) == 0 || i == len_t
                    t_curr = app.thoigian(1:i);
                    for k=1:4
                        % Vẽ vị trí (trừ offset để khớp với GUI)
                        plot(axes_theta{k}, t_curr, rad2deg(Q_traj(k, 1:i)) - app.theta_offset_deg(k), 'b', 'LineWidth', 1.5); 
                        axes_theta{k}.XLim = [0 T_common]; % Fix lỗi xlim warning
                        
                        % Vẽ vận tốc
                        plot(axes_vel{k}, t_curr, rad2deg(V_traj(k, 1:i)), 'r', 'LineWidth', 1.5); 
                        axes_vel{k}.XLim = [0 T_common];
                    end
                end
                drawnow limitrate;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.WindowState = 'maximized';
            app.Init_Geometry_Data();
            app.theta([1 2 3 5]) = deg2rad(app.theta_offset_deg);
            app.old_theta = app.theta(1:4);
            
            % Cài đặt giá trị mặc định
            app.vmaxBox.Value = 20; app.amaxBox.Value = 20;
            app.vmax = deg2rad(20); app.amax = deg2rad(20);
            app.Px_Inv.Value = 324; app.Py_Inv.Value = 0; app.Pz_Inv.Value = 205;
            app.Px = 304; app.Py = 0; app.Pz = 204 - 77;
            
            app.Draw_Robot();

        end

        % Button pushed function: FORWARDButton
        function FORWARDButtonPushed(app, event)
            if app.isBusy
                uialert(app.UIFigure, 'Robot is moving!', 'Warning', 'Icon', 'warning'); return; 
            end
            app.isBusy = true;
            sliders = {app.Theta1Slider, app.Theta2Slider, app.Theta3Slider, app.Theta4Slider};
            fields = {app.Theta1EditField, app.Theta2EditField, app.Theta3EditField, app.Theta4EditField};
            for i=1:4
                val = sliders{i}.Value; fields{i}.Value = val; app.new_theta(i) = deg2rad(val + app.theta_offset_deg(i)); 
            end
            app.Animate_Robot();
            app.isBusy = false;
        end

        % Value changed function: Theta1Slider
        function Theta1SliderValueChanged(app, event)
                val = app.Theta1Slider.Value; app.Theta1EditField.Value = val; app.Update_Single_Joint(1, val);
        end

        % Value changed function: Theta2Slider
        function Theta2SliderValueChanged(app, event)
                val = app.Theta2Slider.Value; app.Theta2EditField.Value = val; app.Update_Single_Joint(2, val);
        end

        % Value changed function: Theta3Slider
        function Theta3SliderValueChanged(app, event)
                val = app.Theta3Slider.Value; app.Theta3EditField.Value = val; app.Update_Single_Joint(3, val);
        end

        % Value changed function: Theta4Slider
        function Theta4SliderValueChanged(app, event)
                val = app.Theta4Slider.Value; app.Theta4EditField.Value = val; app.Update_Single_Joint(4, val);
            
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            if app.isBusy
                uialert(app.UIFigure, 'Robot is moving!', 'Warning', 'Icon', 'warning'); return; 
            end
            app.isBusy = true;
            sliders = {app.Theta1Slider, app.Theta2Slider, app.Theta3Slider, app.Theta4Slider};
            fields = {app.Theta1EditField, app.Theta2EditField, app.Theta3EditField, app.Theta4EditField};
            for i=1:4
                sliders{i}.Value = 0; fields{i}.Value = 0; app.new_theta(i) = deg2rad(app.theta_offset_deg(i)); 
            end
            app.Animate_Robot();
            app.isBusy = false;
        end

        % Button pushed function: Clear
        function ClearButtonPushed(app, event)
            app.robotPath = []; set(app.h_PathPlot, 'XData', [], 'YData', [], 'ZData', []);
        end

        % Value changed function: Theta1EditField
        function Theta1EditFieldValueChanged(app, event)
                val = app.Theta1EditField.Value; app.Theta1Slider.Value = val; app.Update_Single_Joint(1, val);
        end

        % Value changed function: Theta2EditField
        function Theta2EditFieldValueChanged(app, event)
                val = app.Theta2EditField.Value; app.Theta2Slider.Value = val; app.Update_Single_Joint(2, val);
        end

        % Value changed function: Theta3EditField
        function Theta3EditFieldValueChanged(app, event)
                val = app.Theta3EditField.Value; app.Theta3Slider.Value = val; app.Update_Single_Joint(3, val);
        end

        % Value changed function: Theta4EditField
        function Theta4EditFieldValueChanged(app, event)
                val = app.Theta4EditField.Value; app.Theta4Slider.Value = val; app.Update_Single_Joint(4, val);
        end

        % Value changed function: Coordinate_Checkbox
        function Coordinate_CheckboxValueChanged(app, event)
            app.Update_Coordinates();
        end

        % Value changed function: WorkSpaceCheckBox
        function WorkSpaceCheckBoxValueChanged(app, event)
            app.Draw_Robot();
        end

        % Button pushed function: INVERSEButton
        function INVERSEButtonPushed(app, event)
           if app.isBusy
                uialert(app.UIFigure, 'Robot is moving!', 'Warning', 'Icon', 'warning'); return; 
            end
            app.isBusy = true;
            [success, angles] = app.Solve_Inverse_Kinematics(app.Px_Inv.Value, app.Py_Inv.Value, app.Pz_Inv.Value, app.Pitch_Inv.Value);
            if ~success
                uialert(app.UIFigure, 'Target unreachable!', 'Error', 'Icon', 'error'); app.isBusy = false; return; 
            end
            
            app.new_theta = angles;
            % Cập nhật GUI explicit
            sliders = {app.Theta1Slider, app.Theta2Slider, app.Theta3Slider, app.Theta4Slider};
            fields  = {app.Theta1EditField, app.Theta2EditField, app.Theta3EditField, app.Theta4EditField};
            for i = 1:4
                val = rad2deg(app.new_theta(i)) - app.theta_offset_deg(i);
                val = mod(val + 180, 360) - 180; 
                if val < sliders{i}.Limits(1), val = sliders{i}.Limits(1); end
                if val > sliders{i}.Limits(2), val = sliders{i}.Limits(2); end
                sliders{i}.Value = val; fields{i}.Value = val;
            end
            app.Animate_Robot();
            app.isBusy = false;
        end

        % Value changed function: Px_Inv
        function Px_InvValueChanged(app, event)
            app.Px = app.Px_Inv.Value;
        end

        % Value changed function: Py_Inv
        function Py_InvValueChanged(app, event)
            app.Py = app.Py_Inv.Value;
            
        end

        % Value changed function: Pz_Inv
        function Pz_InvValueChanged(app, event)
            app.Pz = app.Pz_Inv.Value - app.d(1);
            
        end

        % Value changed function: Pitch_Inv
        function Pitch_InvValueChanged(app, event)
            app.Pitch  = app.Pitch_Inv.Value;
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            if app.isBusy
                app.stopRequest = true; 
            end
        end

        % Button pushed function: PLANNINGButton
        function PLANNINGButtonPushed(app, event)
            if app.isBusy
                uialert(app.UIFigure, 'Robot is moving!', 'Warning', 'Icon', 'warning'); return; 
            end
            app.isBusy = true;
            if app.vmaxBox.Value <= 0 || app.amaxBox.Value <= 0
                uialert(app.UIFigure, 'Vmax/Amax must be > 0', 'Input Error'); app.isBusy = false; return; 
            end
            app.vmax = deg2rad(app.vmaxBox.Value); app.amax = deg2rad(app.amaxBox.Value);

            [success, angles] = app.Solve_Inverse_Kinematics(app.Px_Inv.Value, app.Py_Inv.Value, app.Pz_Inv.Value, app.Pitch_Inv.Value);
            if ~success
                uialert(app.UIFigure, 'Target unreachable!', 'Error', 'Icon', 'error'); app.isBusy = false; return; 
            end
            
            app.new_theta = angles;
            % Cập nhật GUI explicit
            sliders = {app.Theta1Slider, app.Theta2Slider, app.Theta3Slider, app.Theta4Slider};
            fields  = {app.Theta1EditField, app.Theta2EditField, app.Theta3EditField, app.Theta4EditField};
            for i = 1:4
                val = rad2deg(app.new_theta(i)) - app.theta_offset_deg(i);
                val = mod(val + 180, 360) - 180;
                if val < sliders{i}.Limits(1), val = sliders{i}.Limits(1); end
                if val > sliders{i}.Limits(2), val = sliders{i}.Limits(2); end
                sliders{i}.Value = val; fields{i}.Value = val;
            end
            
            app.LSPB_TrajectoryPlanning();
            app.old_theta = app.new_theta;
            app.isBusy = false;
        end

        % Button pushed function: ClearGraphsButton
        function ClearGraphsButtonPushed(app, event)
           if app.isBusy
                uialert(app.UIFigure, 'Robot is moving!', 'Warning', 'Icon', 'warning'); return; 
            end
            axes = {app.UIAxesTheta1, app.UIAxesTheta2, app.UIAxesTheta3, app.UIAxesTheta4, ...
                    app.UIAxesVel1, app.UIAxesVel2, app.UIAxesVel3, app.UIAxesVel4};
            for ax = axes
                cla(ax{1}); 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Manipulator X')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-500 500];
            app.UIAxes.YLim = [-500 500];
            app.UIAxes.ZLim = [0 700];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTick = [-500 -400 -300 -200 -100 0 100 200 300 400 500];
            app.UIAxes.XTickLabel = {'-500'; '-400'; '-300'; '-200'; '-100'; '0'; '100'; '200'; '300'; '400'; '500'};
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTick = [-500 -400 -300 -200 -100 0 100 200 300 400 500];
            app.UIAxes.YTickLabel = {'-500'; '-400'; '-300'; '-200'; '-100'; '0'; '100'; '200'; '300'; '400'; '500'};
            app.UIAxes.ZTick = [0 100 200 300 400 500 600];
            app.UIAxes.ZTickLabel = {'0'; '100'; '200'; '300'; '400'; '500'; '600'};
            app.UIAxesTheta1 = uiaxes(app.UIFigure);
            title(app.UIAxesTheta1, 'Theta1 (deg)')
            xlabel(app.UIAxesTheta1, 't (s)')
            ylabel(app.UIAxesTheta1, 'P1(t) ')
            app.UIAxesTheta2 = uiaxes(app.UIFigure);
            title(app.UIAxesTheta2, 'Theta2 (deg)')
            xlabel(app.UIAxesTheta2, 't (s)')
            ylabel(app.UIAxesTheta2, 'P2(t) ')
            app.UIAxesTheta3 = uiaxes(app.UIFigure);
            title(app.UIAxesTheta3, 'Theta3 (deg)')
            xlabel(app.UIAxesTheta3, 't (s)')
            ylabel(app.UIAxesTheta3, 'P3(t) ')
            app.UIAxesTheta4 = uiaxes(app.UIFigure);
            title(app.UIAxesTheta4, 'Theta4 (deg)')
            xlabel(app.UIAxesTheta4, 't (s)')
            ylabel(app.UIAxesTheta4, 'P4(t) ')
            app.UIAxesVel1 = uiaxes(app.UIFigure);
            title(app.UIAxesVel1, 'Velocity1 (deg/s)')
            xlabel(app.UIAxesVel1, 't (s)')
            ylabel(app.UIAxesVel1, 'v1(t)')
            app.UIAxesVel2 = uiaxes(app.UIFigure);
            title(app.UIAxesVel2, 'Velocity2 (deg/s)')
            xlabel(app.UIAxesVel2, 't (s)')
            ylabel(app.UIAxesVel2, 'v2(t)')
            app.UIAxesVel3 = uiaxes(app.UIFigure);
            title(app.UIAxesVel3, 'Velocity3 (deg/s)')
            xlabel(app.UIAxesVel3, 't (s)')
            ylabel(app.UIAxesVel3, 'v3(t)')
            app.UIAxesVel4 = uiaxes(app.UIFigure);
            title(app.UIAxesVel4, 'Velocity4(deg/s)')
            xlabel(app.UIAxesVel4, 't (s)')
            ylabel(app.UIAxesVel4, 'v4(t)')
            app.FORWARDPanel = uipanel(app.UIFigure);
            app.FORWARDPanel.Title = 'FORWARD';
            app.Theta1degSliderLabel = uilabel(app.FORWARDPanel);
            app.Theta1degSliderLabel.Text = {'Theta1'; '(deg)'};
            app.Theta1Slider = uislider(app.FORWARDPanel);
            app.Theta1Slider.Limits = [-180 180];
            app.Theta1Slider.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.Theta1Slider.ValueChangedFcn = createCallbackFcn(app, @Theta1SliderValueChanged, true);
            app.Theta2degSliderLabel = uilabel(app.FORWARDPanel);
            app.Theta2degSliderLabel.Text = {'Theta2'; '(deg)'};
            app.Theta2Slider = uislider(app.FORWARDPanel);
            app.Theta2Slider.Limits = [-180 180];
            app.Theta2Slider.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.Theta2Slider.ValueChangedFcn = createCallbackFcn(app, @Theta2SliderValueChanged, true);
            app.Theta3degLabel = uilabel(app.FORWARDPanel);
            app.Theta3degLabel.Text = {'Theta3'; '(deg)'};
            app.Theta3Slider = uislider(app.FORWARDPanel);
            app.Theta3Slider.Limits = [-180 180];
            app.Theta3Slider.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.Theta3Slider.ValueChangedFcn = createCallbackFcn(app, @Theta3SliderValueChanged, true);
            app.Theta4Slider = uislider(app.FORWARDPanel);
            app.Theta4Slider.Limits = [-180 180];
            app.Theta4Slider.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.Theta4Slider.ValueChangedFcn = createCallbackFcn(app, @Theta4SliderValueChanged, true);
            app.Theta1EditField = uieditfield(app.FORWARDPanel, 'numeric');
            app.Theta1EditField.ValueDisplayFormat = '%.2f';
            app.Theta1EditField.ValueChangedFcn = createCallbackFcn(app, @Theta1EditFieldValueChanged, true);
            app.Theta2EditField = uieditfield(app.FORWARDPanel, 'numeric');
            app.Theta2EditField.ValueDisplayFormat = '%.2f';
            app.Theta2EditField.ValueChangedFcn = createCallbackFcn(app, @Theta2EditFieldValueChanged, true);
            app.Theta3EditField = uieditfield(app.FORWARDPanel, 'numeric');
            app.Theta3EditField.ValueDisplayFormat = '%.2f';
            app.Theta3EditField.ValueChangedFcn = createCallbackFcn(app, @Theta3EditFieldValueChanged, true);
            app.Theta4EditField = uieditfield(app.FORWARDPanel, 'numeric');
            app.Theta4EditField.ValueDisplayFormat = '%.2f';
            app.Theta4EditField.ValueChangedFcn = createCallbackFcn(app, @Theta4EditFieldValueChanged, true);
            app.FORWARDButton = uibutton(app.FORWARDPanel, 'push');
            app.FORWARDButton.ButtonPushedFcn = createCallbackFcn(app, @FORWARDButtonPushed, true);
            app.FORWARDButton.Text = 'FORWARD';
            app.Theta4degSliderLabel = uilabel(app.FORWARDPanel);
            app.Theta4degSliderLabel.Text = {'Theta4'; '(deg)'};
            app.RESETButton = uibutton(app.FORWARDPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.Clear = uibutton(app.FORWARDPanel, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.Clear.Text = 'CLEAR';
            app.STOPButton = uibutton(app.FORWARDPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.ENDEFFECTORPanel = uipanel(app.UIFigure);
            app.ENDEFFECTORPanel.Title = 'END - EFFECTOR';
            app.PxEditFieldLabel = uilabel(app.ENDEFFECTORPanel);
            app.PxEditFieldLabel.Text = 'Px';
            app.X_EE = uieditfield(app.ENDEFFECTORPanel, 'numeric');
            app.X_EE.ValueDisplayFormat = '%.2f';
            app.PyEditFieldLabel = uilabel(app.ENDEFFECTORPanel);
            app.PyEditFieldLabel.Text = 'Py';
            app.Y_EE = uieditfield(app.ENDEFFECTORPanel, 'numeric');
            app.Y_EE.ValueDisplayFormat = '%.2f';
            app.PzEditFieldLabel = uilabel(app.ENDEFFECTORPanel);
            app.PzEditFieldLabel.Text = 'Pz';
            app.Z_EE = uieditfield(app.ENDEFFECTORPanel, 'numeric');
            app.Z_EE.ValueDisplayFormat = '%.2f';
            app.PitchEditFieldLabel = uilabel(app.ENDEFFECTORPanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.Pitch_EE = uieditfield(app.ENDEFFECTORPanel, 'numeric');
            app.Pitch_EE.ValueDisplayFormat = '%.2f';
            app.RollEditFieldLabel = uilabel(app.ENDEFFECTORPanel);
            app.RollEditFieldLabel.Text = 'Roll';
            app.Roll_EE = uieditfield(app.ENDEFFECTORPanel, 'numeric');
            app.Roll_EE.ValueDisplayFormat = '%.2f';
            app.YawEditFieldLabel = uilabel(app.ENDEFFECTORPanel);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.Yaw_EE = uieditfield(app.ENDEFFECTORPanel, 'numeric');
            app.Yaw_EE.ValueDisplayFormat = '%.2f';
            app.Coordinate_Checkbox = uicheckbox(app.ENDEFFECTORPanel);
            app.Coordinate_Checkbox.ValueChangedFcn = createCallbackFcn(app, @Coordinate_CheckboxValueChanged, true);
            app.Coordinate_Checkbox.Tag = 'Coordinateall';
            app.Coordinate_Checkbox.Text = 'Coordinate axes';
            app.WorkSpaceCheckBox = uicheckbox(app.ENDEFFECTORPanel);
            app.WorkSpaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @WorkSpaceCheckBoxValueChanged, true);
            app.WorkSpaceCheckBox.Text = 'WorkSpace';
            app.INVERSEPanel = uipanel(app.UIFigure);
            app.INVERSEPanel.Title = 'INVERSE';
            app.INVERSEButton = uibutton(app.INVERSEPanel, 'push');
            app.INVERSEButton.ButtonPushedFcn = createCallbackFcn(app, @INVERSEButtonPushed, true);
            app.INVERSEButton.Text = 'INVERSE';
            app.PxEditFieldLabel_2 = uilabel(app.INVERSEPanel);
            app.PxEditFieldLabel_2.Text = 'Px';
            app.PyEditFieldLabel_2 = uilabel(app.INVERSEPanel);
            app.PyEditFieldLabel_2.Text = 'Py';
            app.PzEditFieldLabel_2 = uilabel(app.INVERSEPanel);
            app.PzEditFieldLabel_2.Text = 'Pz';
            app.Px_Inv = uieditfield(app.INVERSEPanel, 'numeric');
            app.Px_Inv.ValueDisplayFormat = '%.2f';
            app.Px_Inv.ValueChangedFcn = createCallbackFcn(app, @Px_InvValueChanged, true);
            app.Py_Inv = uieditfield(app.INVERSEPanel, 'numeric');
            app.Py_Inv.ValueDisplayFormat = '%.2f';
            app.Py_Inv.ValueChangedFcn = createCallbackFcn(app, @Py_InvValueChanged, true);
            app.Pz_Inv = uieditfield(app.INVERSEPanel, 'numeric');
            app.Pz_Inv.ValueDisplayFormat = '%.2f';
            app.Pz_Inv.ValueChangedFcn = createCallbackFcn(app, @Pz_InvValueChanged, true);
            app.PLANNINGButton = uibutton(app.INVERSEPanel, 'push');
            app.PLANNINGButton.ButtonPushedFcn = createCallbackFcn(app, @PLANNINGButtonPushed, true);
            app.PLANNINGButton.Text = 'PLANNING';
            app.Pitch_Inv = uieditfield(app.INVERSEPanel, 'numeric');
            app.Pitch_Inv.ValueDisplayFormat = '%.2f';
            app.Pitch_Inv.ValueChangedFcn = createCallbackFcn(app, @Pitch_InvValueChanged, true);
            app.PzEditFieldLabel_3 = uilabel(app.INVERSEPanel);
            app.PzEditFieldLabel_3.Text = 'Pitch';
            app.PxEditFieldLabel_3 = uilabel(app.INVERSEPanel);
            app.PxEditFieldLabel_3.Text = 'a_max';
            app.PyEditFieldLabel_3 = uilabel(app.INVERSEPanel);
            app.PyEditFieldLabel_3.Text = 'v_max';
            app.amaxBox = uieditfield(app.INVERSEPanel, 'numeric');
            app.amaxBox.ValueDisplayFormat = '%.2f';
            app.vmaxBox = uieditfield(app.INVERSEPanel, 'numeric');
            app.vmaxBox.ValueDisplayFormat = '%.2f';
            app.ClearGraphsButton = uibutton(app.UIFigure, 'push');
            app.ClearGraphsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearGraphsButtonPushed, true);
            app.ClearGraphsButton.Text = 'CLEAR GRAPHS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManipulatorX

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end